classdef SharingSecret < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ShamirLabel       matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        nSpinnerLabel     matlab.ui.control.Label
        nSpinner          matlab.ui.control.Spinner
        mSpinnerLabel     matlab.ui.control.Label
        mSpinner          matlab.ui.control.Spinner
        nmEditFieldLabel  matlab.ui.control.Label
        nmEditField       matlab.ui.control.EditField
        Panel_2           matlab.ui.container.Panel
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        Button_4          matlab.ui.control.RadioButton
        Button_5          matlab.ui.control.RadioButton
        UITable           matlab.ui.control.Table
        Button_6          matlab.ui.control.Button
        UITable2          matlab.ui.control.Table
        Panel_3           matlab.ui.container.Panel
        Label_4           matlab.ui.control.Label
        UITable2_3        matlab.ui.control.Table
        UITable2_4        matlab.ui.control.Table
        CLabel            matlab.ui.control.Label
        CLabel_2          matlab.ui.control.Label
        UITable2_5        matlab.ui.control.Table
        ButtonGroup_3     matlab.ui.container.ButtonGroup
        Button_9          matlab.ui.control.RadioButton
        Button_10         matlab.ui.control.RadioButton
        Button_11         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Panel_4           matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        EditFieldLabel    matlab.ui.control.Label
        iEditField2       matlab.ui.control.NumericEditField
        iEditFieldLabel   matlab.ui.control.Label
        iEditField        matlab.ui.control.NumericEditField
        UITable3          matlab.ui.control.Table
        Button_14         matlab.ui.control.Button
        Button_13         matlab.ui.control.Button
    end


    properties (Access = private)
        a;%门限
        flag_trapdoor = 0; %门限是否设置成功
        flag_share = 0; %分发是否成功
        s;%给每个人分发的秘密值
        s_add;%加法方案分配给每个人的值求和
        s_mul;%乘法方案分配给每个人的值求积
    end


    methods (Access = private)

        % Button pushed function: Button
        function Trapdoor(app, event)
            temp1 = app.nSpinner.Value;
            temp2 = app.mSpinner.Value;
            app.a = [temp1,temp2];
            if temp2 > temp1
               msgbox("解密人数>总人数，重新输入","提示"); 
            elseif temp2 <= 1
               msgbox("解密人数需大于1","提示"); 
            else
               app.nmEditField.Value = "(" + app.a(1) + "," + app.a(2) + ")";
               app.flag_trapdoor = 1;
               
                temp = char();
                for i = 1:app.a(1)
                     temp = [temp;sprintf('第%3d个人',i)];              
                end 
                app.UITable2.ColumnName = temp;
                app.UITable2_3.ColumnName = temp;
                app.UITable2_4.ColumnName = temp;
                app.UITable2_5.ColumnName = temp;
%                 c = char();
%                 for i = 1:app.a(2)
%                      if i==1
%                          c = ['    常数项 ';c];
%                      else
%                          c = [sprintf('%3d次项系数 ',i-1);c];
%                      end
%                             
%                 end
%                 app.UITable2_3.RowName = c;
                %app.ButtonGroup_2.SelectedObject.Value = 0;

            end
        end

        % Callback function: ButtonGroup_2, UITable2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if app.flag_trapdoor == 0
                msgbox("请先输入门限值","提示");
            else
                c = char();
                for i = 1:app.a(2)
                     if i==1
                         c = ['    常数项 ';c];
                     else
                         c = [sprintf('%3d次项系数 ',i-1);c];
                     end
                            
                end
                
                app.UITable.RowName = c;
                b = zeros(app.a(2),2);             
                app.UITable.Data = b;
                switch selectedButton.Text
                    case "自定义多项式系数"
                        %msgbox('1');
                        app.UITable.Data = b;
                    case "随机生成"
                        %msgbox('2');
                        %app.UITable.RowName = c;
                        %app.UITable.Data = ceil(rand(2,app.a(2))*10)';
                        app.UITable.Data = randi([-10 10],app.a(2),2);
                app.flag_share = 1;        
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.s = Sharing(app.a, app.UITable.Data');
            app.UITable2.Data = app.s';
            app.s
            app.s_add = sum(app.s, 2);
            app.s_mul = prod(app.s, 2);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %t;
            temp = [app.iEditField.Value,app.iEditField2.Value];
            app.UITable3.Data = [app.UITable3.Data;temp];
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if size(app.UITable3.Data,1)<app.a(2)
                msgbox("不足恢复人数","提示");
            else                
                P  = app.UITable3.Data(1:app.a(2),:);%取前m行进行计算
                y = Recover(app.a,P);
                app.TextArea.Value = string(y);
            end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            if app.flag_share ==0
                msgbox("请先完成乘法密钥分发","提示");
            else
                c = char();
                for i = 1:app.a(2)
                     if i==1
                         c = ['    常数项 ';c];
                     else
                         c = [sprintf('%3d次项系数 ',i-1);c];
                     end
                            
                end
                
                b = zeros(app.a(2),app.a(1));             
                app.UITable2_3.Data = b;
                switch selectedButton.Text
                    case "自定义多项式系数"
                        %msgbox('1');
                        app.UITable2_3.ColumnEditable = true;
                        app.UITable2_3.Data = b;
                    case "随机生成"
                        %msgbox('2');
                        app.UITable2_3.RowName = c;
%                        t =  ceil(rand(app.a(1),app.a(2)-1)*10)'
%                        t =  app.s
                       %app.UITable2_3.Data = [ceil(rand(app.a(1),app.a(2)-1)*10)';app.s_mul'];   
                       app.UITable2_3.Data = [randi([-100 100],app.a(2)-1,app.a(1));app.s_mul'];
                end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            CMatrix = CalculateCMatrix(app.a,app.UITable2_3.Data')
            app.UITable2_4.Data = CMatrix;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            %lamda = zeros(1,a(1));
            M = zeros(app.a(1),app.a(1));
            m = [1:1:app.a(1)]';
            for i = 1:app.a(1)
                M(:,i) = m.^(i-1)
            end
            desMatrix = zeros(1,app.a(1));
            desMatrix(1) = 1;
            lamda = desMatrix * inv(M);
            %每个人根据自己得到的Cji计算C值
            C = lamda * app.UITable2_4.Data;
            app.UITable2_5.Data = C;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.UITable.Data = [];
            app.UITable2.Data = [];
            app.UITable2_3.Data = [];
            app.UITable2_4.Data = [];
            app.UITable2_5.Data = [];
            app.UITable3.Data = [];
            app.TextArea.Value = "";
            app.iEditField.Value= 0;
            app.iEditField2.Value= 0;
            app.nmEditField.Value = '';
            app.nSpinner.Value = 0;
            app.mSpinner.Value = 0;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.UITable3.Data = [];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ShamirLabel = uilabel(app.UIFigure);
            app.ShamirLabel.Text = '隐私计算-Shamir秘密共享方案';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '确认门限方案';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Trapdoor, true);
            app.Button.Text = '确定';
            app.nSpinnerLabel = uilabel(app.Panel);
            app.nSpinnerLabel.Text = '总人数(n)：';
            app.nSpinner = uispinner(app.Panel);
            app.nSpinner.Limits = [0 1000];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.mSpinnerLabel = uilabel(app.Panel);
            app.mSpinnerLabel.Text = '解密人数(m)：';
            app.mSpinner = uispinner(app.Panel);
            app.mSpinner.Limits = [0 1000];
            app.mSpinner.ValueDisplayFormat = '%.0f';
            app.nmEditFieldLabel = uilabel(app.Panel);
            app.nmEditFieldLabel.Text = '门限(n,m)：';
            app.nmEditField = uieditfield(app.Panel, 'text');
            app.nmEditField.Editable = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '加法乘法方案子秘密值分发';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = '自定义多项式系数';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup_2);
            app.Button_5.Text = '随机生成';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'第一个多项式参数'; '第二个多项式参数'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '分发子秘密值';
            app.UITable2 = uitable(app.Panel_2);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '乘法方案再分发';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '再分发多项式系数：';
            app.UITable2_3 = uitable(app.Panel_3);
            app.UITable2_3.ColumnName = '';
            app.UITable2_3.RowName = {};
            app.UITable2_4 = uitable(app.Panel_3);
            app.UITable2_4.ColumnName = '';
            app.UITable2_4.RowName = {};
            app.CLabel = uilabel(app.Panel_3);
            app.CLabel.Text = 'C矩阵';
            app.CLabel_2 = uilabel(app.Panel_3);
            app.CLabel_2.Text = '每人计算的C值(子秘密值)';
            app.UITable2_5 = uitable(app.Panel_3);
            app.UITable2_5.ColumnName = '';
            app.UITable2_5.RowName = {};
            app.ButtonGroup_3 = uibuttongroup(app.Panel_3);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.Button_9 = uiradiobutton(app.ButtonGroup_3);
            app.Button_9.Text = '自定义多项式系数';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.ButtonGroup_3);
            app.Button_10.Text = '随机生成';
            app.Button_11 = uibutton(app.Panel_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '确定计算';
            app.Button_12 = uibutton(app.Panel_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '确定计算';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '恢复';
            app.TextArea = uitextarea(app.Panel_4);
            app.TextArea.Editable = 'off';
            app.Button_8 = uibutton(app.Panel_4, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '确定恢复';
            app.Button_7 = uibutton(app.Panel_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '添加';
            app.EditFieldLabel = uilabel(app.Panel_4);
            app.EditFieldLabel.Text = '秘密值';
            app.iEditField2 = uieditfield(app.Panel_4, 'numeric');
            app.iEditFieldLabel = uilabel(app.Panel_4);
            app.iEditFieldLabel.Text = '第i人';
            app.iEditField = uieditfield(app.Panel_4, 'numeric');
            app.UITable3 = uitable(app.Panel_4);
            app.UITable3.ColumnName = {'第i人'; '第i人秘密值'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.Button_14 = uibutton(app.Panel_4, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '清空';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '清空';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SharingSecret

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end